classdef bsci_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Country                       matlab.ui.control.DropDown
        CountryDropDownLabel          matlab.ui.control.Label
        Sets                          matlab.ui.control.EditField
        GroupsLabel                   matlab.ui.control.Label
        Type                          matlab.ui.control.EditField
        TypeEditFieldLabel            matlab.ui.control.Label
        Variable                      matlab.ui.control.EditField
        VariableLabel                 matlab.ui.control.Label
        Abbreviation                  matlab.ui.control.DropDown
        AbbreviationDropDownLabel     matlab.ui.control.Label
        SubsetMember                  matlab.ui.control.DropDown
        MemberLabel_4                 matlab.ui.control.Label
        LoadmemberandsetmeanButton    matlab.ui.control.Button
        Subset                        matlab.ui.control.DropDown
        SubsetLabel                   matlab.ui.control.Label
        MemberGeomap                  matlab.ui.control.DropDown
        MemberGeomapLabel             matlab.ui.control.Label
        SetGeomap                     matlab.ui.control.DropDown
        SetGeomapLabel                matlab.ui.control.Label
        Member                        matlab.ui.control.DropDown
        MemberLabel                   matlab.ui.control.Label
        Set                           matlab.ui.control.DropDown
        SetLabel                      matlab.ui.control.Label
        LoadmemberandworldmeanButton  matlab.ui.control.Button
        UIImageAxes                   matlab.ui.control.UIAxes
        UIAxesGeomap                  matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        indicators
        results
        countryAttributes
        abbs

        setIDlist
        memberIDlist

        groupSets

        scoreSet

        setID
        memberID

        GsetID
        GmemberID

        megaset

        ax
        TempParent

        BarCMap
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%% data and data management
    %data
    app.indicators = readtable('2022-epi.xlsx','Sheet','6_EPI_Weights');
    app.results = readtable('2022-epi.xlsx','Sheet','3_EPI_Results','TreatAsMissing','NA');
    app.countryAttributes = readtable('2022-epi.xlsx','Sheet','7_EPI_Country_Attributes');

    %pull abbreviations (henceforth abbs.) and the weird way in which
    %they are ordered
    order = app.indicators.Order;
    startAbbs = app.indicators.Abbreviation;

    %reorder abbreviations the way they are ordered on results sheet
    %easier to reorder abbs. than to reorder all the data I will pull
    [~,iOrder] = sort(order);
    app.abbs = categorical(startAbbs(iOrder,:));

    %need to (app.abbs,app.abbs) to set this order. otherwise, the cats
    %get alphabetically ordered by bar(), which is useless for the data
    app.abbs = categorical(app.abbs,app.abbs);
    numAbbs = length(app.abbs);

    %find all the members of each set to make memberIDlist
    [world,indWorld] = unique(app.results.country); %setID = 1
    continents = unique(app.countryAttributes.continent); %setID = 2
    regions = unique(app.countryAttributes.region); %setID = 3
    unsdRegions = unique(app.countryAttributes.unsd_region); %setID = 4
    %setID = 5
    groups = table2cell(readtable('2022-epi.xlsx','Sheet','7_EPI_Country_Attributes','ReadVariableNames',0,'Range','N1:AB1'))';

    %to use for setID
    app.setIDlist = {'World' 'Continents' 'Regions' 'UNSD Regions' 'Peer groups'};

    %megalist of every set [(:,1)] with its members [(:,3)] and the members'
    %indices [(:,2)]. relates ID to entity and interrelates between sets
    %and members
    [~,~,app.megaset] = set_calculator(1,1,app.results,app.indicators,app.countryAttributes);

    scoreWorld = nan(height(world),numAbbs);
    scoreContinents = nan(height(continents),numAbbs);
    scoreRegions = nan(height(regions),numAbbs);
    scoreUnsd = nan(height(unsdRegions),numAbbs);
    scoreGroups = nan(height(groups),numAbbs);

    %I want to have dropdown menu be listed in alphabetical order.
    %however, the spreadsheet is not listed in alphabetical
    %order. i need to reorder the scoreWorld matrix so that it matches this
    for i = 1:height(world)
        scoreWorld(i,:) = set_calculator(1,i,app.results,app.indicators,app.countryAttributes);
    end
    scoreWorld = scoreWorld(indWorld,:);

    %use set_calculator to calculate scores for each continent
    for i = 1:height(continents)
        scoreContinents(i,:) = set_calculator(2,i,app.results,app.indicators,app.countryAttributes);
    end

    %same but for regions
    for i = 1:height(regions)
        scoreRegions(i,:) = set_calculator(3,i,app.results,app.indicators,app.countryAttributes);
    end

    %same but for UNSD regions
    for i = 1:height(unsdRegions)
        scoreUnsd(i,:) = set_calculator(4,i,app.results,app.indicators,app.countryAttributes);
    end

    %same but for groups
    for i = 1:height(groups)
        scoreGroups(i,:) = set_calculator(5,i,app.results,app.indicators,app.countryAttributes);
    end
    
    %combine all score cells into one cell for ease of indexing later
    app.scoreSet = {scoreWorld; scoreContinents; scoreRegions; 
        scoreUnsd; scoreGroups};

    %combine all members into one cell for ease of indexing later
    app.memberIDlist = {world; continents; 
        regions; unsdRegions; groups};
%% EPI axes
    %startUp values
    app.setID = 1;
    app.memberID = 1;

    %startUp dropdown settings
    app.Set.Items = app.setIDlist;
    app.Member.Items = app.megaset{app.setID}(:,3);

    %pull hardcoded BarCMap to color-code bar graphs
    app.BarCMap = final_barcmap;

    %color-code starting bar graph
    yyaxis(app.UIAxes,"left")
    b = bar(app.UIAxes,app.abbs,app.scoreSet{app.setID}(app.memberID,:));
    hold(app.UIAxes, 'on');
    for i = 1:length(app.abbs)
        b.FaceColor = 'flat'; %make the bars only have one, flat color
        b.CData(i,:) = app.BarCMap(i,:); %set the RGB for ith bar
        if isnan(app.scoreSet{app.setID}(app.memberID,i)) %if ith var is NaN
            plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2); %plot red x at y = 0
            hold(app.UIAxes, 'on');
        end
    end
    hold(app.UIAxes, 'off');


    for i = 1:length(app.abbs)
    end

    %startUp second y-axis
    ylim(app.UIAxes,[0 100])
    yyaxis(app.UIAxes,"right");
    ylim(app.UIAxes,[0 100])
    ylabel(app.UIAxes,"Score (out of 100)")
    yyaxis(app.UIAxes,"left")
%% geomap
    app.GsetID = 1;
    app.GmemberID = 1;

    app.SetGeomap.Items = app.setIDlist;
    app.MemberGeomap.Items = app.megaset{app.GsetID}(:,3);

    %startUp Geomap
    app.ax = geomap4final(app.megaset{app.GsetID},app.GmemberID); %create geomap
    app.TempParent = app.ax.Parent; %create temporary parent
    app.ax.Parent = app.UIAxesGeomap.Parent; %set parent of app axes to geomap parent
    app.ax.Units = 'pixel'; %make sure units are pixels so that the geomap works
    app.ax.Position = app.UIAxesGeomap.Position; %set geomap over app axes
    app.UIAxesGeomap.Visible = 'off'; %make app axes invisible
    %i have to use the invisible app axes' titles and labels because
    %otherwise i'd have to edit the bordersm function to use the geomap titles
    app.UIAxesGeomap.Title.Visible = 'on'; %keep app axes title visible
    app.UIAxesGeomap.XLabel.Visible = 'on'; %and xlabel
    app.UIAxesGeomap.YLabel.Visible = 'on'; %and ylabel
    app.UIAxesGeomap.Title.Position = [0.5 1.05 0.5]; %put title correctly
    app.UIAxesGeomap.XLabel.Position = [0.5 -0.1 0]; %same for xlabel
    app.UIAxesGeomap.YLabel.Position = [0.0005 0.5 0]; %same for ylabel
    app.UIAxesGeomap.Title.String = append("Map of ",app.megaset{app.setID}{app.memberID,3}); %set dynamic title
    app.UIAxesGeomap.XLabel.String = "Longitude (º)"; %set xlabel title
    app.UIAxesGeomap.YLabel.String = "Latitude (º)"; %set ylabel title
    delete(app.TempParent); %delete tempparent so no fig pops up behind app

    %turn off top level dropdowns to start
    %since single nations don't have subsets
    app.Subset.Visible = 'off';
    app.SubsetLabel.Visible = 'off';
    app.LoadmemberandsetmeanButton.Visible = 'off';
    app.SubsetMember.Visible = 'off';
    app.MemberLabel_4.Visible = 'off';
    
    %EPI axis labels
    app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,3});
    app.UIAxes.XLabel.String = "2022 EPI Variable";
    app.UIAxes.YLabel.String = "Score (out of 100)";

    abbsID = 1;
    %start up abbreviation info settings
    app.Abbreviation.Items = app.abbs;
    app.Variable.Value = string(cell2mat(app.indicators.Variable(abbsID)));
    app.Type.Value = string(cell2mat(app.indicators.Type(abbsID)));

%%
    %start up per country group info settings in bottom right
    app.Country.Items = app.megaset{app.setID}(:,3);

    %this helps find which groups each member is in by member, rather than
    %by group. needed for the subset graphing
    app.groupSets = cell(height(app.megaset{1}),3);
        for g = 1:height(app.megaset{1})
        %set first column of groupSets to country name
        app.groupSets{g,1} = app.megaset{1}{g,3};
        %set middle column to app.results_inds of each country
        app.groupSets{g,2} = app.megaset{1}{g,2};
        n = 0;
        %for every country, find and store the groups in which it is and
        %include them in one cell in that country's row
            for gg = 1:length(app.megaset{5})
            cgInds = app.megaset{5}{gg,2};
                if find(app.groupSets{g,2}==cgInds) & isempty(app.groupSets{g,3}) 
                    app.groupSets{g,3}{1} = app.megaset{5}{gg,1};
                    n = 1;
                elseif find(app.groupSets{g,2}==cgInds) & ~isempty(app.groupSets{g,3})
                    n = n + 1;
                    app.groupSets{g,3}{n} = app.megaset{5}{gg,1};
                end
            end
        end

    groupingMemberID = 1;
    %create and readout starting info in per country group info
    setsStr = "none";
            groups = app.groupSets{groupingMemberID,3};
            for n = 1:length(groups)
                if n == 1
                    setsStr = groups{n};
                else
                    setsStr = append(setsStr,", ",groups{n});
                end
            end
    app.Sets.Value = setsStr;
%% load hierarchy image
    image = imread("2022-epi-framework.png");
    imshow(image,'Parent',app.UIImageAxes)
        end

        % Button pushed function: LoadmemberandworldmeanButton
        function LoadmemberandworldmeanButtonPushed(app, event)
    value = app.Member.Value;
    %assign country name to numeric ID for indexing use
    app.memberID = find(strcmp(value,app.memberIDlist{app.setID})==1);

    %plot category/member bar graph when this button is hit in case
    %the previous bar graph is for the set/member
    yyaxis(app.UIAxes,"left")
    b = bar(app.UIAxes,app.abbs,app.scoreSet{app.setID}(app.memberID,:));
    hold(app.UIAxes, 'on');
    for i = 1:length(app.abbs)
        b.FaceColor = 'flat';
        b.CData(i,:) = app.BarCMap(i,:);
        if isnan(app.scoreSet{app.setID}(app.memberID,i))
            plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
            hold(app.UIAxes, 'on');
        end
    end
    hold(app.UIAxes, 'off');
    ylim(app.UIAxes,[0 100])

    %make sure title matches category/member bar, not set/member bar
    if app.setID == 1
        app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,3});
    elseif app.setID > 1
        app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,1});
    end

    %clear mean line in case it is for set mean, not world mean
    yyaxis(app.UIAxes,"right")
    cla(app.UIAxes)

    %calculate world mean using my function
    [~,meanResult] = set_calculator(app.setID,app.memberID,app.results,app.indicators,app.countryAttributes);

    %plot its mean line
    yyaxis(app.UIAxes,"right");
    plot(app.UIAxes,app.abbs,meanResult,'LineWidth',2.5)
    ylim(app.UIAxes,[0 100])
    yyaxis(app.UIAxes,"left")
        end

        % Value changed function: Set
        function SetValueChanged(app, event)
            value = app.Set.Value;
            %assign set to numeric ID for indexing use
            app.setID = find(strcmp(value,app.setIDlist)==1);
            %always put memberID to 1 when resetting set
            app.memberID = 1;

            %clear mean line when changing bar graph
            yyaxis(app.UIAxes,"right")
            cla(app.UIAxes)
            yyaxis(app.UIAxes,"left")
            
            %setID == 1 needs to be separate because its indexing is
            %slightly different and because the top level dropdowns
            %need to be invisible
            if app.setID == 1
                %{app.setID}(:,3) because megaset{1} is structured diff
                app.Member.Items = app.megaset{app.setID}(:,3);
                b = bar(app.UIAxes,app.abbs,app.scoreSet{app.setID}(app.memberID,:));
                hold(app.UIAxes, 'on');
                for i = 1:length(app.abbs)
                    b.FaceColor = 'flat';
                    b.CData(i,:) = app.BarCMap(i,:);
                    if isnan(app.scoreSet{app.setID}(app.memberID,i))
                        plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
                        hold(app.UIAxes, 'on');
                    end
                end
                hold(app.UIAxes, 'off');
                ylim(app.UIAxes,[0 100])

                app.Subset.Visible = 'off';
                app.SubsetLabel.Visible = 'off';
                app.LoadmemberandsetmeanButton.Visible = 'off';
                app.SubsetMember.Visible = 'off';
                app.MemberLabel_4.Visible = 'off';

                %(:,3)
                app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,3});
            else
            %other setIDs can all be indexed the same way and have the
            %top level dropdowns visible
                yyaxis(app.UIAxes,"right")
                cla(app.UIAxes)
                yyaxis(app.UIAxes,"left")
                
                app.Member.Items = app.megaset{app.setID}(:,1);
                b = bar(app.UIAxes,app.abbs,app.scoreSet{app.setID}(app.memberID,:));
                hold(app.UIAxes, 'on');
                for i = 1:length(app.abbs)
                    b.FaceColor = 'flat';
                    b.CData(i,:) = app.BarCMap(i,:);
                    if isnan(app.scoreSet{app.setID}(app.memberID,i))
                        plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
                        hold(app.UIAxes, 'on');
                    end
                end
                hold(app.UIAxes, 'off');
                ylim(app.UIAxes,[0 100])

                app.Subset.Visible = 'on';
                app.SubsetLabel.Visible = 'on';
                app.LoadmemberandsetmeanButton.Visible = 'on';
                app.SubsetMember.Visible = 'on';
                app.MemberLabel_4.Visible = 'on';

                app.Subset.Items = cellstr(app.megaset{app.setID}{app.memberID,1});
                app.SubsetMember.Items = app.megaset{app.setID}{app.memberID,3};

                app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,1});
            end
        end

        % Value changed function: Member
        function MemberValueChanged(app, event)
            value = app.Member.Value;
            app.memberID = find(strcmp(value,app.memberIDlist{app.setID})==1);
            
            %same thing as setID changing but no visibility changes
            %for top level dropdowns because that depends on setID
            b = bar(app.UIAxes,app.abbs,app.scoreSet{app.setID}(app.memberID,:));
            hold(app.UIAxes, 'on');
            for i = 1:length(app.abbs)
                b.FaceColor = 'flat';
                b.CData(i,:) = app.BarCMap(i,:);
                if isnan(app.scoreSet{app.setID}(app.memberID,i))
                    plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
                    hold(app.UIAxes, 'on');
                end
            end
            hold(app.UIAxes, 'off');
            ylim(app.UIAxes,[0 100])

            %clear from previous data and put new member list(s) and title in place
            if app.setID == 1
                yyaxis(app.UIAxes,"right")
                cla(app.UIAxes)
                yyaxis(app.UIAxes,"left")

                %(:,3)
                app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,3});
            else
                yyaxis(app.UIAxes,"right")
                cla(app.UIAxes)
                yyaxis(app.UIAxes,"left")

                app.Subset.Items = cellstr(app.megaset{app.setID}{app.memberID,1});
                app.SubsetMember.Items = app.megaset{app.setID}{app.memberID,3};

                %(:,1)
                app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{app.setID}{app.memberID,1});
            end
        end

        % Value changed function: SetGeomap
        function SetGeomapValueChanged(app, event)
            value = app.SetGeomap.Value;
            %new kind of setID and memberID so that there are no
            %bugs by sharing the same vars
            app.GsetID = find(strcmp(value,app.setIDlist)==1);
            app.GmemberID = 1;
            
            %reset member dropdown to new GsetID
            %indexing diff between GsetID = 1 and all others
            if app.GsetID == 1
                %(:,3)
                app.MemberGeomap.Items = app.megaset{app.GsetID}(:,3);
            else
                %(:,1)
                app.MemberGeomap.Items = app.megaset{app.GsetID}(:,1);
            end

                cla(app.ax) %clear axis in case preexisting geomap

                %set new geomap for GsetID, GmemberID = 1
                app.ax = geomap4final(app.megaset{app.GsetID},app.GmemberID);
                app.TempParent = app.ax.Parent;
                app.ax.Parent = app.UIAxesGeomap.Parent;
                app.ax.Units = 'pixel';
                app.ax.Position = app.UIAxesGeomap.Position;
                app.UIAxesGeomap.Visible = 'off';
                delete(app.TempParent);
                app.UIAxesGeomap.Title.Position = [0.5 1.05 0.5];
                app.UIAxesGeomap.Title.Position = [0.5 1.05 0.5];
                app.UIAxesGeomap.XLabel.Position = [0.5 -0.1 0];
                app.UIAxesGeomap.YLabel.Position = [0.0005 0.5 0];

            %change dynamic title to new GsetID, GmemberID = 1
            %indexing diff between GsetID = 1 and all others
            if app.GsetID == 1
                %(:,3)
                app.UIAxesGeomap.Title.String = append("Map of ",app.megaset{app.GsetID}{app.GmemberID,3});
            else
                %(:,1)
                app.UIAxesGeomap.Title.String = append("Map of ",app.megaset{app.GsetID}{app.GmemberID,1});
            end
        end

        % Value changed function: MemberGeomap
        function MemberGeomapValueChanged(app, event)
            value = app.MemberGeomap.Value;
            app.GmemberID = find(strcmp(value,app.memberIDlist{app.GsetID})==1);

            %set new geomap for new GmemberID
            cla(app.ax)
            app.ax = geomap4final(app.megaset{app.GsetID},app.GmemberID);
            app.TempParent = app.ax.Parent;
            app.ax.Parent = app.UIAxesGeomap.Parent;
            app.ax.Units = 'pixel';
            app.ax.Position = app.UIAxesGeomap.Position;
            app.UIAxesGeomap.Visible = 'off';
            delete(app.TempParent);
            app.UIAxesGeomap.Title.Position = [0.5 1.05 0.5];
            app.UIAxesGeomap.Title.Position = [0.5 1.05 0.5];
            app.UIAxesGeomap.XLabel.Position = [0.5 -0.1 0];
            app.UIAxesGeomap.YLabel.Position = [0.0005 0.5 0];
            
            %change dynamic title to new GmemberID
            if app.GsetID == 1
                %(:,3)
                app.UIAxesGeomap.Title.String = append("Map of ",app.megaset{app.GsetID}{app.GmemberID,3});
            else
                %(:,1)
                app.UIAxesGeomap.Title.String = append("Map of ",app.megaset{app.GsetID}{app.GmemberID,1});
            end
        end

        % Button pushed function: LoadmemberandsetmeanButton
        function LoadmemberandsetmeanButtonPushed(app, event)
            yyaxis(app.UIAxes,"right")
            %clear previous mean line data off graph
            cla(app.UIAxes)

            %index giant score cell for the needed set EPI data
            setMeanResult = app.scoreSet{app.setID}(app.memberID,:);

            %plot the set EPI data
            plot(app.UIAxes,app.abbs,setMeanResult,'LineWidth',2.5)
            ylim(app.UIAxes,[0 100])

            yyaxis(app.UIAxes,"left")
            value = app.SubsetMember.Value;
            foo = 1;
            %pretending foo is setID so I can get memberID without
            %changing setID or doing something longer
            subsetMemberID = find(strcmp(value,app.memberIDlist{foo})==1);

            %load graph for set/member instead of keeping category/member
            b = bar(app.UIAxes,app.abbs,app.scoreSet{foo}(subsetMemberID,:));
            hold(app.UIAxes, 'on');
            for i = 1:length(app.abbs)
                b.FaceColor = 'flat';
                b.CData(i,:) = app.BarCMap(i,:);
                if isnan(app.scoreSet{foo}(subsetMemberID,i))
                    plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
                    hold(app.UIAxes, 'on');
                end
            end
            hold(app.UIAxes, 'off');
            ylim(app.UIAxes,[0 100])

            %change title to match
            app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{foo}{subsetMemberID,3});
        end

        % Value changed function: SubsetMember
        function SubsetMemberValueChanged(app, event)
            value = app.SubsetMember.Value;
            foo = 1;
            %pretending foo is setID so I can get memberID without
            %changing setID or doing something longer
            subsetMemberID = find(strcmp(value,app.memberIDlist{foo})==1);

            %clear mean line just in case one plotted before
            yyaxis(app.UIAxes,"right")
            cla(app.UIAxes)
            yyaxis(app.UIAxes,"left")

            %load graph for new member of set/member
            b = bar(app.UIAxes,app.abbs,app.scoreSet{foo}(subsetMemberID,:));
            hold(app.UIAxes, 'on');
            for i = 1:length(app.abbs)
                b.FaceColor = 'flat';
                b.CData(i,:) = app.BarCMap(i,:);
                if isnan(app.scoreSet{foo}(subsetMemberID,i))
                    plot(app.UIAxes,app.abbs(i),0,'rx','MarkerSize',10,'LineWidth',2);
                    hold(app.UIAxes, 'on');
                end
            end
            hold(app.UIAxes, 'off');
            ylim(app.UIAxes,[0 100])

            %change title
            app.UIAxes.Title.String = append("2022 Environmental Performance Index for ",app.megaset{foo}{subsetMemberID,3});
        end

        % Value changed function: Abbreviation
        function AbbreviationValueChanged(app, event)
            value = app.Abbreviation.Value;
            %set value to new dropdown selection
            
            %convert that abbreviation in cell form to a string
            Abbs = string(cell2mat(app.indicators.Abbreviation));
            %find and store its index
            abbsID = find(strcmp(value,Abbs)==1);

            %pull up the description for that abbreviation using its index
            app.Variable.Value = string(cell2mat(app.indicators.Variable(abbsID)));
            %pull up the type for that abbreviation using its index
            app.Type.Value = string(cell2mat(app.indicators.Type(abbsID))); 
        end

        % Value changed function: Country
        function CountryValueChanged(app, event)
            value = app.Country.Value;
            
            %pretending foo is setID so I can get memberID without
            %changing setID or doing something longer
            foo = 1;

            %find and store index of country of dropdown selection
            groupingMemberID = strcmp(value,app.memberIDlist{foo})==1;

            %start string output as none in case a country has no groups
            setsStr = "none";

            %from groupSets, measure and store number of groups for the
            %selected country
            groupNum = app.groupSets{groupingMemberID,3};
            for n = 1:length(groupNum)
                %for the first group, make that replace "none"
                if n == 1
                    setsStr = groupNum{n};
                else %groups thereafter get added onto the first with a comma
                    setsStr = append(setsStr,", ",groupNum{n});
                end
            end

            %make resulting string the output
            app.Sets.Value = setsStr;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesGeomap = uiaxes(app.UIFigure);
            title(app.UIAxesGeomap, 'Title')
            xlabel(app.UIAxesGeomap, 'X')
            ylabel(app.UIAxesGeomap, 'Y')
            zlabel(app.UIAxesGeomap, 'Z')
            app.UIImageAxes = uiaxes(app.UIFigure);
            app.UIImageAxes.XTick = [];
            app.UIImageAxes.YTick = [];
            app.LoadmemberandworldmeanButton = uibutton(app.UIFigure, 'push');
            app.LoadmemberandworldmeanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmemberandworldmeanButtonPushed, true);
            app.LoadmemberandworldmeanButton.Text = 'Load member and world mean';
            app.SetLabel = uilabel(app.UIFigure);
            app.SetLabel.Text = 'Category';
            app.Set = uidropdown(app.UIFigure);
            app.Set.ValueChangedFcn = createCallbackFcn(app, @SetValueChanged, true);
            app.MemberLabel = uilabel(app.UIFigure);
            app.MemberLabel.Text = 'Member';
            app.Member = uidropdown(app.UIFigure);
            app.Member.ValueChangedFcn = createCallbackFcn(app, @MemberValueChanged, true);
            app.SetGeomapLabel = uilabel(app.UIFigure);
            app.SetGeomapLabel.Text = 'Category';
            app.SetGeomap = uidropdown(app.UIFigure);
            app.SetGeomap.ValueChangedFcn = createCallbackFcn(app, @SetGeomapValueChanged, true);
            app.MemberGeomapLabel = uilabel(app.UIFigure);
            app.MemberGeomapLabel.Text = 'Member';
            app.MemberGeomap = uidropdown(app.UIFigure);
            app.MemberGeomap.ValueChangedFcn = createCallbackFcn(app, @MemberGeomapValueChanged, true);
            app.SubsetLabel = uilabel(app.UIFigure);
            app.SubsetLabel.Text = 'Set';
            app.Subset = uidropdown(app.UIFigure);
            app.LoadmemberandsetmeanButton = uibutton(app.UIFigure, 'push');
            app.LoadmemberandsetmeanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmemberandsetmeanButtonPushed, true);
            app.LoadmemberandsetmeanButton.Text = 'Load member and set mean';
            app.MemberLabel_4 = uilabel(app.UIFigure);
            app.MemberLabel_4.Text = 'Member';
            app.SubsetMember = uidropdown(app.UIFigure);
            app.SubsetMember.ValueChangedFcn = createCallbackFcn(app, @SubsetMemberValueChanged, true);
            app.AbbreviationDropDownLabel = uilabel(app.UIFigure);
            app.AbbreviationDropDownLabel.Text = 'Abbreviation';
            app.Abbreviation = uidropdown(app.UIFigure);
            app.Abbreviation.ValueChangedFcn = createCallbackFcn(app, @AbbreviationValueChanged, true);
            app.VariableLabel = uilabel(app.UIFigure);
            app.VariableLabel.Text = 'Variable';
            app.Variable = uieditfield(app.UIFigure, 'text');
            app.TypeEditFieldLabel = uilabel(app.UIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.Type = uieditfield(app.UIFigure, 'text');
            app.GroupsLabel = uilabel(app.UIFigure);
            app.GroupsLabel.Text = 'Group(s)';
            app.Sets = uieditfield(app.UIFigure, 'text');
            app.CountryDropDownLabel = uilabel(app.UIFigure);
            app.CountryDropDownLabel.Text = 'Country';
            app.Country = uidropdown(app.UIFigure);
            app.Country.ValueChangedFcn = createCallbackFcn(app, @CountryValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bsci_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end